classdef ROIPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SelectfolderButton     matlab.ui.control.Button
        ListBox                matlab.ui.control.ListBox
        TextArea               matlab.ui.control.TextArea
        UIAxes                 matlab.ui.control.UIAxes
        ROIButtonGroup         matlab.ui.container.ButtonGroup
        ParticleButton         matlab.ui.control.RadioButton
        NoiseButton            matlab.ui.control.RadioButton
        ClearlastButton        matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        ROIRadiusSpinnerLabel  matlab.ui.control.Label
        ROIRadiusSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        roiStruct = struct(); % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            global filePath fileList
            filePath = uigetdir();
            fileList = dir(fullfile(filePath,'*.tif'));
            app.ListBox.Items = {fileList.name};
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            global filePath fileName image
            fileName = app.ListBox.Value;
            image = imread(fullfile(filePath,fileName));
            hold(app.UIAxes,'off');
            imagesc(app.UIAxes,image);
            hold(app.UIAxes,'on');
            app.UIAxes.Colormap = gray;
            app.UIAxes.DataAspectRatio = [1 1 1];
            try
                for roiId = 1 : length(app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi)
                    if app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(roiId).particle
                        plot(app.UIAxes,...
                            app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(roiId).colNum ,...
                            app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(roiId).rowNum ,...
                            '+g');
                    else
                        plot(app.UIAxes,...
                            app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(roiId).colNum ,...
                            app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(roiId).rowNum ,...
                            '+r');
                    end
                end
            catch
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            global fileName image
            coordinates = round(get(app.UIAxes,'CurrentPoint'));
            if coordinates(1,2) - app.ROIRadiusSpinner.Value > 1 && ...
                    coordinates(1,2) + app.ROIRadiusSpinner.Value < size(image,1) && ...
                    coordinates(1,1) - app.ROIRadiusSpinner.Value > 1 && ...
                    coordinates(1,1) + app.ROIRadiusSpinner.Value < size(image,2)
                if app.ParticleButton.Value
                    plot(app.UIAxes,coordinates(1,1),coordinates(1,2),'+g');
                else
                    plot(app.UIAxes,coordinates(1,1),coordinates(1,2),'+r');
                end
                if app.ParticleButton.Value
                    particle = 1;
                else
                    particle = 0;
                end
                try
                    app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(end + 1).rowNum = coordinates(1,2);
                    app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(end).colNum = coordinates(1,1);
                    app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(end).particle = particle;
                catch
                    app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(1).rowNum = coordinates(1,2);
                    app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(1).colNum = coordinates(1,1);
                    app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(1).particle = particle;
                end
            end
        end

        % Button pushed function: ClearlastButton
        function ClearlastButtonPushed(app, event)
            global fileName image
            app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(end) = [];
            hold(app.UIAxes,'off');
            imagesc(app.UIAxes,image);
            hold(app.UIAxes,'on');
            app.UIAxes.Colormap = gray;
            app.UIAxes.DataAspectRatio = [1 1 1];
            for roiId = 1 : length(app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi)
                if app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(roiId).particle == 1
                    plot(app.UIAxes,...
                        app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(roiId).colNum ,...
                        app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(roiId).rowNum ,...
                        '+g');
                else
                    plot(app.UIAxes,...
                        app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(roiId).colNum ,...
                        app.roiStruct.file(strcmp(app.ListBox.Items,fileName)).roi(roiId).rowNum ,...
                        '+r');
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global filePath
            globalRoiId = 0;
            indices = [];
            imageVec = struct();
            classVec = struct();
            for fileId = 1 : length(app.roiStruct.file)
                if ~isempty(app.roiStruct.file(fileId).roi)
                    indices = [indices fileId];
                end
            end
            for fileId = 1 : length(indices)
                fileName = app.ListBox.Items{indices(fileId)};
                image = imread(fullfile(filePath,fileName));
                for roiId = 1 : length(app.roiStruct.file(indices(fileId)).roi)
                    globalRoiId = globalRoiId + 1;
                    imageVecTmp(1 : 2 * (app.ROIRadiusSpinner.Value) + 1, ...
                        1 : 2 * (app.ROIRadiusSpinner.Value) + 1, ...
                        1, ...
                        globalRoiId) = ...
                        image(app.roiStruct.file(indices(fileId)).roi(roiId).rowNum - app.ROIRadiusSpinner.Value : ...
                        app.roiStruct.file(indices(fileId)).roi(roiId).rowNum + app.ROIRadiusSpinner.Value, ...
                        app.roiStruct.file(indices(fileId)).roi(roiId).colNum - app.ROIRadiusSpinner.Value: ...
                        app.roiStruct.file(indices(fileId)).roi(roiId).colNum + app.ROIRadiusSpinner.Value);
                    classVecTmp(globalRoiId) = app.roiStruct.file(indices(fileId)).roi(roiId).particle;
                end
            end
            imageVec.noise(1).array = imageVecTmp;
            classVec.noise(1).array = classVecTmp;
            save(['imageVec_' num2str(app.ROIRadiusSpinner.Value)],'imageVec');
            save(['classVec_' num2str(app.ROIRadiusSpinner.Value)],'classVec');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.SelectfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.TextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ROIButtonGroup = uibuttongroup(app.UIFigure);
            app.ROIButtonGroup.Title = 'ROI';
            app.ParticleButton = uiradiobutton(app.ROIButtonGroup);
            app.ParticleButton.Text = 'Particle';
            app.ParticleButton.Value = true;
            app.NoiseButton = uiradiobutton(app.ROIButtonGroup);
            app.NoiseButton.Text = 'Noise';
            app.ClearlastButton = uibutton(app.UIFigure, 'push');
            app.ClearlastButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlastButtonPushed, true);
            app.ClearlastButton.Text = 'Clear last';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ROIRadiusSpinnerLabel = uilabel(app.UIFigure);
            app.ROIRadiusSpinnerLabel.Text = 'ROI Radius';
            app.ROIRadiusSpinner = uispinner(app.UIFigure);
            app.ROIRadiusSpinner.Limits = [1 5];
            app.ROIRadiusSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIPicker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end